classdef Span_Task < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        PatientIDTab                 matlab.ui.container.Tab
        SetUpLabel                   matlab.ui.control.Label
        CommentsEditField            matlab.ui.control.EditField
        CommentsEditFieldLabel       matlab.ui.control.Label
        DemographicsEditField        matlab.ui.control.EditField
        DemographicsEditFieldLabel   matlab.ui.control.Label
        DateDatePicker               matlab.ui.control.DatePicker
        DateDatePickerLabel          matlab.ui.control.Label
        ParticipantIDEditField       matlab.ui.control.EditField
        ParticipantIDEditFieldLabel  matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        TaskOptionsTab               matlab.ui.container.Tab
        ConsecutiveFailSpinner       matlab.ui.control.Spinner
        ConsecutiveFailSpinnerLabel  matlab.ui.control.Label
        LengthSpinner                matlab.ui.control.Spinner
        LengthSpinnerLabel           matlab.ui.control.Label
        OptionsDropDown              matlab.ui.control.DropDown
        OptionsDropDownLabel         matlab.ui.control.Label
        TestTypeLabel                matlab.ui.control.Label
        StartingSpanLengthLabel      matlab.ui.control.Label
        TaskTab                      matlab.ui.container.Tab
        NoSaveButton                 matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        KEYLabel                     matlab.ui.control.Label
        CommentsTextArea             matlab.ui.control.TextArea
        ANSWERLabel                  matlab.ui.control.Label
        SPANLabel                    matlab.ui.control.Label
        DigitSpanLabel               matlab.ui.control.Label
        SpanInfoLabel                matlab.ui.control.Label
        NameIDLabel                  matlab.ui.control.Label
        LOADEDCONFIGURATIONLabel     matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        WrongButton                  matlab.ui.control.Button
        RightButton                  matlab.ui.control.Button
        ClickButtontoBeginTaskLabel  matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        VerbalDigitSpanTaskLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        patientInfo     % Patient Information
        taskInfo        % Instructions on task
        Timer           % Timer
        Sound           % Contains 1-10 number sounds

        Results         % Recording Right/Wrong & Time
        CurrentTask     % Keeps track of ongoing task
    end
    
    methods (Access = public)
    end
    
    methods (Access = private)
        
        function Y = loadsounds(app, span)
            % Selects the sounds according to input span and returns a cell 
            % array containing all of the necessary sounds. For loops are
            % later utilized in app to run through the sounds and played.


            Y = cell(length(span),1);                                      % initializing required cell size
            idx = 1;                                                       % idexes into cell
            for i = span
                if i == 0                                                  % edge case for digit 0
                    Y{idx} = app.Sound{10};
                else
                    Y{idx} = app.Sound{i};
                end
                idx = idx + 1;                                             % updates index
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function defaultLoad(app)
            % on START-UP
            app.patientInfo.ID           = 'Unknown';
            app.patientInfo.date         = '';
            app.patientInfo.demographics = '';
            app.patientInfo.comments     = '';

            app.taskInfo.spanLength      = 4;
            app.taskInfo.orientation     = 'Forward';
            app.taskInfo.failstopnum     = 2;

            % handling what's visible and what's not
            app.StartButton.Visible                 = "on";
            app.RightButton.Visible                 = "off";
            app.WrongButton.Visible                 = "off";
            app.StopButton.Visible                  = "off";
            app.ANSWERLabel.Visible                 = "off";
            app.SPANLabel.Visible                   = "off";
            app.CommentsTextArea.Visible            = "off";
            app.KEYLabel.Visible                    = "off";
            app.ClickButtontoBeginTaskLabel.Visible = "on";
            app.NoSaveButton.Visible                = "off";
            app.SaveButton.Visible                  = "off";

            % loading sounds
            [Y, Fs]   = audioread('countdown_male.mp3');
            [Y2, Fs2] = audioread('go.wav');
            [Y3, Fs3] = audioread('prepare.wav');

            %              9    8    7    6    5    4    3    2    1    0
            start_time = [1.2; 2.2; 3.0; 4.0; 4.9; 5.9; 6.7; 7.5; 8.4; 9.3];
            stop_time  = [2.0; 2.9; 3.8; 4.8; 5.5; 6.5; 7.4; 8.2; 9.1; 10.2];
            
            tempsound = cell(10);
            for i = 1:length(start_time)
                Y_start_idx  = round(start_time(i)*Fs);
                Y_stop_idx   = round(stop_time(i)*Fs);
                Y_extract    = Y(Y_start_idx:Y_stop_idx);
                tempsound{i} = Y_extract;
            end
            
            app.Sound     = cell(12);                                      % loading into app Property
            % Note: This explicit audioplayer assignment is only way this
            % works smoothly. Beware if trying to optimize.
            app.Sound{9}  = audioplayer(tempsound{1}, Fs);
            app.Sound{8}  = audioplayer(tempsound{2}, Fs);
            app.Sound{7}  = audioplayer(tempsound{3}, Fs);
            app.Sound{6}  = audioplayer(tempsound{4}, Fs);
            app.Sound{5}  = audioplayer(tempsound{5}, Fs);
            app.Sound{4}  = audioplayer(tempsound{6}, Fs);
            app.Sound{3}  = audioplayer(tempsound{7}, Fs);
            app.Sound{2}  = audioplayer(tempsound{8}, Fs);
            app.Sound{1}  = audioplayer(tempsound{9}, Fs);
            app.Sound{10} = audioplayer(tempsound{10},Fs);
            app.Sound{11} = audioplayer(Y2, Fs2);                          % Go Sound
            app.Sound{12} = audioplayer(Y3, Fs3);                          % Prepare
        end

        % Value changed function: ParticipantIDEditField
        function NameID(app, event)
            patientID = app.ParticipantIDEditField.Value;
            app.patientInfo.ID = patientID;
        end

        % Value changed function: DateDatePicker
        function DatePicked(app, event)
            value = app.DateDatePicker.Value;
            app.patientInfo.date = value;
        end

        % Value changed function: DemographicsEditField
        function demoGraphics(app, event)
            value = app.DemographicsEditField.Value;
            app.patientInfo.demographics = value;
        end

        % Value changed function: CommentsEditField
        function comments(app, event)
            value = app.CommentsEditField.Value;
            app.patientInfo.comments = value;
        end

        % Callback function
        function SpanLength(app, event)
            value = app.RoundstonearestwholenumberSlider.Value;
            app.taskInfo.spanLength = value;
        end

        % Callback function
        function orientationSpan(app, event)
            value = app.Switch.Value;
            app.taskInfo.orientation = value;
        end

        % Callback function
        function saveAll(app, event)

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % visibility 
            app.StartButton.Visible                  = "off";
            app.RightButton.Visible                  = "on";
            app.WrongButton.Visible                  = "on";
            app.StopButton.Visible                   = "on";
            app.ANSWERLabel.Visible                  = "on";
            app.SPANLabel.Visible                    = "on";
            app.CommentsTextArea.Visible             = "on";
            app.KEYLabel.Visible                     = "on";
            app.ClickButtontoBeginTaskLabel.Visible  = "off";
            [app.taskInfo.Data, app.taskInfo.Key] = Verbal_Digit_Span(app.taskInfo); % task data

            % Results Specific
            app.Results.data  = cell(25, 5);                               % initialize results
            app.Results.idx   = 1;                                         % index keeping order
            app.DigitSpanLabel.Text = sprintf("Digit Span Number: %d", app.Results.idx); % starting visual counter

            % Initiating task counters and variables
            app.CurrentTask.row        = 1;                                % controls trial #
            app.CurrentTask.col        = 1;                                % controls span #
            app.CurrentTask.Wrong      = 0;                                % keeps track of wrong #
            app.CurrentTask.Correct    = 0;                                % keeps track of right #
            app.CurrentTask.Span       = app.taskInfo.Data{...
                                            app.CurrentTask.row,...
                                            app.CurrentTask.col};          % selects current span digits
            app.SPANLabel.Text         = num2str(app.CurrentTask.Span);    % shows current digits
            app.CurrentTask.Answer     = app.taskInfo.Key{...
                                            app.CurrentTask.row,...
                                            app.CurrentTask.col};          % selects current Key digits
            app.ANSWERLabel.Text       = num2str(app.CurrentTask.Answer);  % shows current key
            
            % get ready
            play(app.Sound{12})
            pause(2)

            % starting timer and recording
            orange = [255/255, 165/255, 0];
            app.StatusLamp.Color = orange;                                 % recording & time 'on'
            app.Timer = 1;                                                 % 1 = record, 0 = pass
            tic                                                            % starting clock

            % speech
            Y = loadsounds(app, app.CurrentTask.Span);
            for i = 1:length(Y)
                pause(0.25); play(Y{i}); pause(1.2);
            end

            % go
            play(app.Sound{11})
            pause(2)
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            value = app.OptionsDropDown.Value;
            app.taskInfo.orientation = value;
        end

        % Value changed function: LengthSpinner
        function LengthSpinnerValueChanged(app, event)
            value = app.LengthSpinner.Value;
            app.taskInfo.spanLength = value;
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            temp = toc;                                                    % instant pressed
            if app.Timer == 1                                              % only record if record = 1
                app.Results.data{app.Results.idx, 1} = app.CurrentTask.col;
                app.Results.data{app.Results.idx, 2} = temp;
                app.Results.data{app.Results.idx, 3} = app.CurrentTask.Span;
                app.Results.data{app.Results.idx, 4} = 1;
                app.Results.data{app.Results.idx, 5} = app.CommentsTextArea.Value{:};

                app.CommentsTextArea.Value           = '';                 % reset comment space
            end
            
            % update numbers
            app.Results.idx = app.Results.idx + 1;
            app.CurrentTask.Correct = app.CurrentTask.Correct + 1;         % correct + 1
            app.CurrentTask.Wrong = 0;                                     % resetting number of wrong
            if app.CurrentTask.Correct == 2                                % checking for consecutive right
                app.CurrentTask.Correct = 0;                               % resetting correct to 0 for new span
                app.CurrentTask.col = app.CurrentTask.col + 1;             % new span
                app.CurrentTask.row = 1;                                   % first row
            else
                app.CurrentTask.row = app.CurrentTask.row + 1;             % otherwise just updates row
            end
            
            % update shown labels
            app.DigitSpanLabel.Text = sprintf("Digit Span Number: %d", app.Results.idx); % starting visual counter
            app.CurrentTask.Span       = app.taskInfo.Data{...
                                            app.CurrentTask.row,...
                                            app.CurrentTask.col};          % selects current span digits
            app.SPANLabel.Text         = num2str(app.CurrentTask.Span);    % shows current digits
            app.CurrentTask.Answer     = app.taskInfo.Key{...
                                            app.CurrentTask.row,...
                                            app.CurrentTask.col};          % selects current Key digits
            app.ANSWERLabel.Text       = num2str(app.CurrentTask.Answer);  % shows current key
            
            % get ready
            play(app.Sound{12})
            pause(1)

            % speech
            Y = loadsounds(app, app.CurrentTask.Span);
            for i = 1:length(Y)
                pause(0.25); play(Y{i}); pause(1.3);
            end

            % go
            play(app.Sound{11})
            pause(2)
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.Timer = 0;                                                 % recording off
            app.StatusLamp.Color = [0.80,0.80,0.80];

            app.StartButton.Visible                 = "off";
            app.RightButton.Visible                 = "off";
            app.WrongButton.Visible                 = "off";
            app.StopButton.Visible                  = "off";
            app.DigitSpanLabel.Text                 = "Digit Span #";      % resetting visual counter
            app.ANSWERLabel.Visible                 = "off";
            app.SPANLabel.Visible                   = "off";
            app.CommentsTextArea.Visible            = "off";
            app.KEYLabel.Visible                    = "off";
            app.ClickButtontoBeginTaskLabel.Visible = "off";
            app.SaveButton.Visible                  = "on";
            app.NoSaveButton.Visible                = "on";
        end

        % Button pushed function: WrongButton
        function WrongButtonPushed(app, event)
            temp = toc;                                                    % instant pressed
            if app.Timer == 1                                              % only record if record = 1
                app.Results.data{app.Results.idx, 1} = app.CurrentTask.col;
                app.Results.data{app.Results.idx, 2} = temp;
                app.Results.data{app.Results.idx, 3} = app.CurrentTask.Span;
                app.Results.data{app.Results.idx, 4} = 0;
                app.Results.data{app.Results.idx, 5} = app.CommentsTextArea.Value{:};

                app.CommentsTextArea.Value           = '';                 % reset comment space
            end
            
            % update numbers
            app.Results.idx = app.Results.idx + 1;
            app.CurrentTask.Correct = 0;                                   % resets correct counter
            app.CurrentTask.Wrong = app.CurrentTask.Wrong + 1;             % wrong + 1
            if app.CurrentTask.Wrong == app.taskInfo.failstopnum           % checking for consecutive wrong
                app.Timer = 0;                                             % recording off
                app.StatusLamp.Color = [0.80,0.80,0.80];
                
                app.StartButton.Visible                 = "off";
                app.RightButton.Visible                 = "off";
                app.WrongButton.Visible                 = "off";
                app.StopButton.Visible                  = "off";
                app.DigitSpanLabel.Text                 = "Digit Span #";  % resetting visual counter
                app.ANSWERLabel.Visible                 = "off";
                app.SPANLabel.Visible                   = "off";
                app.CommentsTextArea.Visible            = "off";
                app.KEYLabel.Visible                    = "off";
                app.ClickButtontoBeginTaskLabel.Visible = "off";
                app.SaveButton.Visible                  = "on";
                app.NoSaveButton.Visible                = "on"; 

            else
                app.CurrentTask.row = app.CurrentTask.row + 1;             % otherwise just updates row
                
                % update shown labels
                app.DigitSpanLabel.Text = sprintf("Digit Span Number: %d", app.Results.idx); % starting visual counter
                app.CurrentTask.Span    = app.taskInfo.Data{...
                                            app.CurrentTask.row,...
                                            app.CurrentTask.col};          % selects current span digits
                app.SPANLabel.Text      = num2str(app.CurrentTask.Span);   % shows current digits
                app.CurrentTask.Answer  = app.taskInfo.Key{...
                                                app.CurrentTask.row,...
                                                app.CurrentTask.col};      % selects current Key digits
                app.ANSWERLabel.Text    = num2str(app.CurrentTask.Answer); % shows current key
                
                % get ready
                play(app.Sound{12})
                pause(1)

                % speech
                Y = loadsounds(app, app.CurrentTask.Span);
                for i = 1:length(Y)
                    pause(0.25); play(Y{i}); pause(1.3);
                end

                % go
                play(app.Sound{11})
                pause(2)
            end
        end

        % Button down function: TaskTab
        function TaskTabButtonDown(app, event)
            % Task Options Selected
            app.NameIDLabel.Text   = sprintf("NameID: %s", app.patientInfo.ID);
            temp = [app.taskInfo.orientation, string(app.taskInfo.spanLength)];
            app.SpanInfoLabel.Text = sprintf("Orientation: %s, Starting Span Length: %s",temp(1), temp(2));
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            temp = cell(6,5);                                              % ugly but it works
            temp{1,1} = "PATIENT ID";
            temp{1,2} = app.patientInfo.ID;
            temp{2,1} = "DATE:";
            temp{2,2} = app.patientInfo.date;
            temp{3,1} = "DEMOGRAPHIC";
            temp{3,2} = app.patientInfo.demographics;
            temp{4,1} = "COMMENTS:";
            temp{4,2} = app.patientInfo.comments;
            temp{5,1} = "ORIENTATION";
            temp{5,2} = app.taskInfo.orientation;
            
            temp{6,1} = "SPAN LENGTH";
            temp{6,2} = "TIME";
            temp{6,3} = "DIGITS";
            temp{6,4} = "CORRECT";
            temp{6,5} = "COMMENTS";

            outfile = [temp; app.Results.data];
            filename = sprintf('SpanTask_%s.csv', app.patientInfo.ID);
            writetable(cell2table(outfile),filename, ...
                'WriteVariableNames', false, 'quotestrings', true);        % creating CSV

            app.StartButton.Visible         = "on";
            app.RightButton.Visible         = "off";
            app.WrongButton.Visible         = "off";
            app.StopButton.Visible          = "off";
            app.DigitSpanLabel.Text         = "Digit Span #";              % resetting visual counter
            app.ANSWERLabel.Visible         = "off";
            app.SPANLabel.Visible           = "off";
            app.CommentsTextArea.Visible    = "off";
            app.KEYLabel.Visible            = "off";
            app.ClickButtontoBeginTaskLabel.Visible  = "on";
            app.SaveButton.Visible          = "off";
            app.NoSaveButton.Visible        = "off";
        end

        % Button pushed function: NoSaveButton
        function NoSaveButtonPushed(app, event)
            % resets back to start (does nothing)
            app.StartButton.Visible         = "on";
            app.RightButton.Visible         = "off";
            app.WrongButton.Visible         = "off";
            app.StopButton.Visible          = "off";
            app.DigitSpanLabel.Text         = "Digit Span #";              % resetting visual counter
            app.ANSWERLabel.Visible         = "off";
            app.SPANLabel.Visible           = "off";
            app.CommentsTextArea.Visible    = "off";
            app.KEYLabel.Visible            = "off";
            app.ClickButtontoBeginTaskLabel.Visible  = "on";
            app.SaveButton.Visible          = "off";
            app.NoSaveButton.Visible        = "off";
        end

        % Button down function: TaskOptionsTab
        function TaskOptionsTabButtonDown(app, event)
            % resets back to start (does nothing)
            app.Timer = 0;                                                 % recording off
            app.StatusLamp.Color = [0.80,0.80,0.80];
            
            app.StartButton.Visible         = "on";
            app.RightButton.Visible         = "off";
            app.WrongButton.Visible         = "off";
            app.StopButton.Visible          = "off";
            app.DigitSpanLabel.Text         = "Digit Span #";              % resetting visual counter
            app.ANSWERLabel.Visible         = "off";
            app.SPANLabel.Visible           = "off";
            app.CommentsTextArea.Visible    = "off";
            app.KEYLabel.Visible            = "off";
            app.ClickButtontoBeginTaskLabel.Visible  = "on";
            app.SaveButton.Visible          = "off";
            app.NoSaveButton.Visible        = "off";
        end

        % Value changed function: ConsecutiveFailSpinner
        function ConsecutiveFailSpinnerValueChanged(app, event)
            value = app.ConsecutiveFailSpinner.Value;
            app.taskInfo.failstopnum = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VerbalDigitSpanTaskLabel = uilabel(app.UIFigure);
            app.VerbalDigitSpanTaskLabel.Text = 'Verbal Digit Span Task';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PatientIDTab = uitab(app.TabGroup);
            app.PatientIDTab.Title = 'Patient ID';
            app.Image = uiimage(app.PatientIDTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Images', 'Profile.png');
            app.ParticipantIDEditFieldLabel = uilabel(app.PatientIDTab);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.PatientIDTab, 'text');
            app.ParticipantIDEditField.ValueChangedFcn = createCallbackFcn(app, @NameID, true);
            app.DateDatePickerLabel = uilabel(app.PatientIDTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.PatientIDTab);
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DatePicked, true);
            app.DemographicsEditFieldLabel = uilabel(app.PatientIDTab);
            app.DemographicsEditFieldLabel.Text = 'Demographics';
            app.DemographicsEditField = uieditfield(app.PatientIDTab, 'text');
            app.DemographicsEditField.ValueChangedFcn = createCallbackFcn(app, @demoGraphics, true);
            app.CommentsEditFieldLabel = uilabel(app.PatientIDTab);
            app.CommentsEditFieldLabel.Text = 'Comments';
            app.CommentsEditField = uieditfield(app.PatientIDTab, 'text');
            app.CommentsEditField.ValueChangedFcn = createCallbackFcn(app, @comments, true);
            app.SetUpLabel = uilabel(app.PatientIDTab);
            app.SetUpLabel.Text = 'Set-Up';
            app.TaskOptionsTab = uitab(app.TabGroup);
            app.TaskOptionsTab.Title = 'Task Options';
            app.TaskOptionsTab.ButtonDownFcn = createCallbackFcn(app, @TaskOptionsTabButtonDown, true);
            app.StartingSpanLengthLabel = uilabel(app.TaskOptionsTab);
            app.StartingSpanLengthLabel.Text = 'Starting Span Length';
            app.TestTypeLabel = uilabel(app.TaskOptionsTab);
            app.TestTypeLabel.Text = 'Test Type';
            app.OptionsDropDownLabel = uilabel(app.TaskOptionsTab);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.TaskOptionsTab);
            app.OptionsDropDown.Items = {'Forward', 'Backward', 'Sequential'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'Forward';
            app.LengthSpinnerLabel = uilabel(app.TaskOptionsTab);
            app.LengthSpinnerLabel.Text = 'Length';
            app.LengthSpinner = uispinner(app.TaskOptionsTab);
            app.LengthSpinner.Limits = [2 10];
            app.LengthSpinner.ValueChangedFcn = createCallbackFcn(app, @LengthSpinnerValueChanged, true);
            app.LengthSpinner.Value = 4;
            app.ConsecutiveFailSpinnerLabel = uilabel(app.TaskOptionsTab);
            app.ConsecutiveFailSpinnerLabel.Text = 'Consecutive Fail';
            app.ConsecutiveFailSpinner = uispinner(app.TaskOptionsTab);
            app.ConsecutiveFailSpinner.Limits = [2 10];
            app.ConsecutiveFailSpinner.ValueChangedFcn = createCallbackFcn(app, @ConsecutiveFailSpinnerValueChanged, true);
            app.ConsecutiveFailSpinner.Value = 2;
            app.TaskTab = uitab(app.TabGroup);
            app.TaskTab.Title = 'Task';
            app.TaskTab.ButtonDownFcn = createCallbackFcn(app, @TaskTabButtonDown, true);
            app.StartButton = uibutton(app.TaskTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StatusLampLabel = uilabel(app.TaskTab);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.TaskTab);
            app.ClickButtontoBeginTaskLabel = uilabel(app.TaskTab);
            app.ClickButtontoBeginTaskLabel.Text = 'Click Button to Begin Task';
            app.RightButton = uibutton(app.TaskTab, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.WrongButton = uibutton(app.TaskTab, 'push');
            app.WrongButton.ButtonPushedFcn = createCallbackFcn(app, @WrongButtonPushed, true);
            app.WrongButton.Text = 'Wrong';
            app.StopButton = uibutton(app.TaskTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LOADEDCONFIGURATIONLabel = uilabel(app.TaskTab);
            app.LOADEDCONFIGURATIONLabel.Text = 'LOADED CONFIGURATION';
            app.NameIDLabel = uilabel(app.TaskTab);
            app.NameIDLabel.Text = 'NameID';
            app.SpanInfoLabel = uilabel(app.TaskTab);
            app.SpanInfoLabel.Text = 'SpanInfo';
            app.DigitSpanLabel = uilabel(app.TaskTab);
            app.DigitSpanLabel.Text = 'Digit Span #';
            app.SPANLabel = uilabel(app.TaskTab);
            app.SPANLabel.Text = 'SPAN';
            app.ANSWERLabel = uilabel(app.TaskTab);
            app.ANSWERLabel.Text = 'ANSWER';
            app.CommentsTextArea = uitextarea(app.TaskTab);
            app.KEYLabel = uilabel(app.TaskTab);
            app.KEYLabel.Text = 'KEY:';
            app.SaveButton = uibutton(app.TaskTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NoSaveButton = uibutton(app.TaskTab, 'push');
            app.NoSaveButton.ButtonPushedFcn = createCallbackFcn(app, @NoSaveButtonPushed, true);
            app.NoSaveButton.Text = 'No Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Span_Task

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @defaultLoad)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end