classdef Stroop_Task < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CUMULATIVETIMELabel           matlab.ui.control.Label
        PHOTODIODELabel               matlab.ui.control.Label
        NoSaveButton                  matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        TabGroup                      matlab.ui.container.TabGroup
        SetUpTab                      matlab.ui.container.Tab
        SetUpLabel                    matlab.ui.control.Label
        NameIDEditField               matlab.ui.control.EditField
        NameIDEditFieldLabel          matlab.ui.control.Label
        CommentsTextArea              matlab.ui.control.TextArea
        CommentsTextAreaLabel         matlab.ui.control.Label
        DemographicsTextArea          matlab.ui.control.TextArea
        DemographicsTextAreaLabel     matlab.ui.control.Label
        DateDatePicker                matlab.ui.control.DatePicker
        DateDatePickerLabel           matlab.ui.control.Label
        TaskOptionsTab                matlab.ui.container.Tab
        ColorsPanel                   matlab.ui.container.Panel
        BrownCheckBox                 matlab.ui.control.CheckBox
        PinkCheckBox                  matlab.ui.control.CheckBox
        YellowCheckBox                matlab.ui.control.CheckBox
        WhiteCheckBox                 matlab.ui.control.CheckBox
        GrayCheckBox                  matlab.ui.control.CheckBox
        BlueCheckBox                  matlab.ui.control.CheckBox
        OrangeCheckBox                matlab.ui.control.CheckBox
        RedCheckBox                   matlab.ui.control.CheckBox
        GreenCheckBox                 matlab.ui.control.CheckBox
        PurpleCheckBox                matlab.ui.control.CheckBox
        ShowTimeSwitch                matlab.ui.control.Switch
        ShowTimeSwitchLabel           matlab.ui.control.Label
        NumberofColorsSpinner         matlab.ui.control.Spinner
        NumberofColorsSpinnerLabel    matlab.ui.control.Label
        StimulationDurationEditField  matlab.ui.control.NumericEditField
        StimulationDurationEditFieldLabel  matlab.ui.control.Label
        TaskOptionsLabel              matlab.ui.control.Label
        TaskTab                       matlab.ui.container.Tab
        STARTButton                   matlab.ui.control.Button
        STROOPTASKCONTROLLabel        matlab.ui.control.Label
        COLORLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        patientInfo                 % ID
        taskInfo                    % Task

        currentInfo                 % Ongoing
        colors                      % All Colors RGB
        time                        % Recording Response Time
        results                     % Output doc to save with details 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % on START-UP
            app.patientInfo.ID           = 'Unknown';
            app.patientInfo.date         = '';
            app.patientInfo.demographics = '';
            app.patientInfo.comments     = '';
            app.taskInfo.duration        = 60;                             % seconds
            app.taskInfo.colors          = 10;                             % all colors
            app.currentInfo.status       = 0;                              % on/off---> 1/0
            app.taskInfo.showtime        = 0;

            % colors
            app.colors = cell(10,1);
            app.colors{1}  = [130/255, 0, 172/255];                        % purple
            app.colors{2}  = [0, 149/255, 67/255];                         % green
            app.colors{3}  = [225/255, 6/255, 0/255];                      % red
            app.colors{4}  = [253/255, 130/255, 42/255];                   % orange
            app.colors{5}  = [0, 71/255, 189/255];                         % blue
            app.colors{6}  = [136/255, 139/255, 141/255];                  % grey
            app.colors{7}  = [240/255, 243/255, 244/255];                  % white
            app.colors{8}  = [255/255, 230/255, 50/255];                   % yellow
            app.colors{9}  = [251/255, 72/255, 196/255];                   % pink
            app.colors{10} = [101/255, 56/255, 24/255];                    % brown

            app.COLORLabel.Visible   = 'off';
            app.NoSaveButton.Visible = 'off';
            app.SaveButton.Visible   = 'off';
            app.PHOTODIODELabel.Visible = 'off';

            % results.data:
            % trial #, color, word, time, response time, correct/incorrect
            app.results.data = cell(200, 6);

            % time 
            app.time.cumulative = 0;
            
        end

        % Value changed function: NameIDEditField
        function NameIDEditFieldValueChanged(app, event)
            value = app.NameIDEditField.Value;
            app.patientInfo.ID = value;
        end

        % Value changed function: DateDatePicker
        function DateDatePickerValueChanged(app, event)
            value = app.DateDatePicker.Value;
            app.patientInfo.date = value;
        end

        % Value changed function: DemographicsTextArea
        function DemographicsTextAreaValueChanged(app, event)
            value = app.DemographicsTextArea.Value;
            app.patientInfo.demographics = value;
        end

        % Value changed function: CommentsTextArea
        function CommentsTextAreaValueChanged(app, event)
            value = app.CommentsTextArea.Value;
            app.patientInfo.comments = value;
        end

        % Value changed function: StimulationDurationEditField
        function StimulationDurationEditFieldValueChanged(app, event)
            value = app.StimulationDurationEditField.Value;
            app.taskInfo.duration = value;
        end

        % Value changed function: NumberofColorsSpinner
        function NumberofColorsSpinnerValueChanged(app, event)
            value = app.NumberofColorsSpinner.Value;
            app.taskInfo.colors = value;
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            app.TabGroup.Visible                = 'off';
            app.STROOPTASKCONTROLLabel.Visible  = 'off';
            app.COLORLabel.Visible              = 'on';                    % showing color label
            app.UIFigure.Color                  = [3/255, 5/255, 4/255];   % black screen
            
            
            % get ready
            app.COLORLabel.Text            = 'GET READY';
            app.COLORLabel.FontColor       = [1,1,1];

            % current 
            app.currentInfo.data = Stroop_Data_Generate(app.taskInfo.colors);   % generate data to run
            
            % cumulative counter
            app.CUMULATIVETIMELabel.Text = num2str(app.time.cumulative);
            if app.taskInfo.showtime == 1
                app.CUMULATIVETIMELabel.Visible = 'on';
                app.CUMULATIVETIMELabel.Text    = "Cumulative Time";
            else
                app.CUMULATIVETIMELabel.Visible = 'off';
            end

            pause(8)

            % status on
            app.currentInfo.status = 1;
            app.currentInfo.index  = 1;

            % START DIODE
            app.PHOTODIODELabel.Visible    = 'on';
            
            % START FIRST ONE
            app.currentInfo.color_idx  = app.currentInfo.data{app.currentInfo.index}(2);
            app.currentInfo.color_name = app.currentInfo.data{app.currentInfo.index}(1);
            app.COLORLabel.FontColor = app.colors{str2double(app.currentInfo.color_idx{:})};
            app.COLORLabel.Text      = app.currentInfo.color_name{:};
           
            % time
            tic
            pause(0.15)
            app.PHOTODIODELabel.Visible = 'off';
        end

        % Key press function: UIFigure
        function Simulation(app, event)
            key = event.Key;
            
            if app.currentInfo.status == 1
%                 % CHECKING FOR TIME LIMIT
%                 if app.time.cumulative >= app.taskInfo.duration
%                     app.currentInfo.status              = 0;               % off
%                     app.TabGroup.Visible                = 'off';
%                     app.UIFigure.Color                  = [0.94,0.94,0.94];% white screen
%                     app.CUMULATIVETIMELabel.Visible     = 'off';
%                     app.CUMULATIVETIMELabel.Text        = num2str(0);
% 
%                     app.COLORLabel.Text                 = "END OF SIMULATION";
%                     app.COLORLabel.FontColor            = [0,0,0];
%                     pause(3)
%                     app.COLORLabel.Visible              = 'off';           % removing color label
%                     app.STROOPTASKCONTROLLabel.Visible  = 'on';
%                     app.NoSaveButton.Visible            = 'on';
%                     app.SaveButton.Visible              = 'on';
%                 end

                switch key
                    case 'space'                                           % CORRECT
                        % START DIODE
                        app.PHOTODIODELabel.Visible = 'on';
                        
                        app.time.current    = toc;                         % secure reaction time
                        app.time.cumulative = app.time.current + app.time.cumulative;   % update cumulative
                        app.CUMULATIVETIMELabel.Text = num2str(app.time.cumulative);
                        
                        % CHECKING FOR TIME LIMIT LOOKS UGLY BUT IT SHOULD
                        % WORK
                        if app.time.cumulative >= app.taskInfo.duration
                            app.currentInfo.status              = 0;               % off
                            app.TabGroup.Visible                = 'off';
                            app.UIFigure.Color                  = [0.94,0.94,0.94];% white screen
                            app.CUMULATIVETIMELabel.Visible     = 'off';
                            app.CUMULATIVETIMELabel.Text        = num2str(0);
        
                            app.COLORLabel.Text                 = "END OF SIMULATION";
                            app.COLORLabel.FontColor            = [0,0,0];
                            pause(3)
                            app.COLORLabel.Visible              = 'off';           % removing color label
                            app.STROOPTASKCONTROLLabel.Visible  = 'on';
                            app.NoSaveButton.Visible            = 'on';
                            app.SaveButton.Visible              = 'on';
                        end

                        % results
                        app.results.data{app.currentInfo.index, 1} = app.currentInfo.index;
                        app.results.data{app.currentInfo.index, 2} = str2double(app.currentInfo.color_idx{:});
                        app.results.data{app.currentInfo.index, 3} = app.currentInfo.color_name{:};
                        app.results.data{app.currentInfo.index, 4} = app.time.cumulative;
                        app.results.data{app.currentInfo.index, 5} = app.time.current;
                        app.results.data{app.currentInfo.index, 6} = 1;
                        
                        % updating
                        app.currentInfo.index = app.currentInfo.index + 1;
                        app.currentInfo.color_idx  = app.currentInfo.data{app.currentInfo.index}(2);
                        app.currentInfo.color_name = app.currentInfo.data{app.currentInfo.index}(1);
                        app.COLORLabel.FontColor = app.colors{str2double(app.currentInfo.color_idx{:})};
                        app.COLORLabel.Text      = app.currentInfo.color_name{:};
                        
                        % time for next
                        tic
                        pause(0.15)
                        app.PHOTODIODELabel.Visible = 'off';
                    case 'm'                                               % INCORRECT
                        % START DIODE
                        app.PHOTODIODELabel.Visible = 'on';

                        app.time.current    = toc;                         % secure reaction time
                        app.time.cumulative = app.time.current + app.time.cumulative;   % update cumulative
                        app.CUMULATIVETIMELabel.Text = num2str(app.time.cumulative);
                        
                        % CHECKING FOR TIME LIMIT LOOKS UGLY BUT IT SHOULD
                        % WORK
                        if app.time.cumulative >= app.taskInfo.duration
                            app.currentInfo.status              = 0;               % off
                            app.TabGroup.Visible                = 'off';
                            app.UIFigure.Color                  = [0.94,0.94,0.94];% white screen
                            app.CUMULATIVETIMELabel.Visible     = 'off';
                            app.CUMULATIVETIMELabel.Text        = num2str(0);
        
                            app.COLORLabel.Text                 = "END OF SIMULATION";
                            app.COLORLabel.FontColor            = [0,0,0];
                            pause(3)
                            app.COLORLabel.Visible              = 'off';           % removing color label
                            app.STROOPTASKCONTROLLabel.Visible  = 'on';
                            app.NoSaveButton.Visible            = 'on';
                            app.SaveButton.Visible              = 'on';
                        end

                        % results
                        app.results.data{app.currentInfo.index, 1} = app.currentInfo.index;
                        app.results.data{app.currentInfo.index, 2} = str2double(app.currentInfo.color_idx{:});
                        app.results.data{app.currentInfo.index, 3} = app.currentInfo.color_name{:};
                        app.results.data{app.currentInfo.index, 4} = app.time.cumulative;
                        app.results.data{app.currentInfo.index, 5} = app.time.current;
                        app.results.data{app.currentInfo.index, 6} = 0;
                        
                        % updating
                        app.currentInfo.index = app.currentInfo.index + 1;
                        app.currentInfo.color_idx  = app.currentInfo.data{app.currentInfo.index}(2);
                        app.currentInfo.color_name = app.currentInfo.data{app.currentInfo.index}(1);
                        app.COLORLabel.FontColor = app.colors{str2double(app.currentInfo.color_idx{:})};
                        app.COLORLabel.Text      = app.currentInfo.color_name{:};
                        
                        % time for next
                        tic
                        pause(0.15)
                        app.PHOTODIODELabel.Visible = 'off';
                    case 'r'                                               % FIX LAST
                        app.results.data{app.currentInfo.index-1, 6} = 1;
                    case 'escape'                                          % EARLY STOP
                        app.currentInfo.status              = 0;           % off
                        app.TabGroup.Visible                = 'off';
                        app.UIFigure.Color                  = [0.94,0.94,0.94];% white screen
                        
                        app.COLORLabel.Text = "EARLY STOP";
                        app.COLORLabel.FontColor = [0,0,0];
                        pause(3)
                        app.COLORLabel.Visible   = 'off';                  % removing color label
                        app.STROOPTASKCONTROLLabel.Visible  = 'on';
                        app.NoSaveButton.Visible = 'on';
                        app.SaveButton.Visible   = 'on';
                        
                        app.CUMULATIVETIMELabel.Text      = num2str(0);
                        app.CUMULATIVETIMELabel.Visible   = 'off';
                end
            end
        end

        % Button pushed function: NoSaveButton
        function NoSaveButtonPushed(app, event)
            app.NoSaveButton.Visible            = 'off';
            app.SaveButton.Visible              = 'off';
            app.TabGroup.Visible                = 'on';
            app.time.cumulative                 = 0;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            temp = cell(6,6);                                              % ugly but it works
            temp{1,1} = "PATIENT ID";
            temp{1,2} = app.patientInfo.ID;
            temp{2,1} = "DATE:";
            temp{2,2} = app.patientInfo.date;
            temp{3,1} = "DEMOGRAPHIC";
            temp{3,2} = app.patientInfo.demographics;
            temp{4,1} = "COMMENTS:";
            temp{4,2} = app.patientInfo.comments;
            
            temp{6,1} = "TRIAL";
            temp{6,2} = "STIM COLOR";
            temp{6,3} = "STIM WORD";
            temp{6,4} = "STIM PRESENTATION TIME";
            temp{6,5} = "RESPONSE TIME";
            temp{6,6} = "CORRECT/INCORRECT";
            
            outfile = [temp; app.results.data];
            filename = sprintf('StroopTask_%s.csv', app.patientInfo.ID);
            writetable(cell2table(outfile),filename, ...
                'WriteVariableNames', false, 'quotestrings', true);        % creating CSV

            app.NoSaveButton.Visible    = 'off';
            app.SaveButton.Visible      = 'off';
            app.TabGroup.Visible        = 'on';
            app.time.cumulative         = 0;
        end

        % Value changed function: ShowTimeSwitch
        function ShowTimeSwitchValueChanged(app, event)
            value = app.ShowTimeSwitch.Value;
            switch value
                case 'Off'
                    app.taskInfo.showtime = 0;
                    disp(0)
                case 'On'
                    app.taskInfo.showtime = 1;
                    disp(1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @Simulation, true);
            app.COLORLabel = uilabel(app.UIFigure);
            app.COLORLabel.Text = 'COLOR';
            app.STROOPTASKCONTROLLabel = uilabel(app.UIFigure);
            app.STROOPTASKCONTROLLabel.Text = 'STROOP TASK CONTROL';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetUpTab = uitab(app.TabGroup);
            app.SetUpTab.Title = 'Set-Up';
            app.DateDatePickerLabel = uilabel(app.SetUpTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.SetUpTab);
            app.DateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DateDatePickerValueChanged, true);
            app.DemographicsTextAreaLabel = uilabel(app.SetUpTab);
            app.DemographicsTextAreaLabel.Text = 'Demographics';
            app.DemographicsTextArea = uitextarea(app.SetUpTab);
            app.DemographicsTextArea.ValueChangedFcn = createCallbackFcn(app, @DemographicsTextAreaValueChanged, true);
            app.CommentsTextAreaLabel = uilabel(app.SetUpTab);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.CommentsTextArea = uitextarea(app.SetUpTab);
            app.CommentsTextArea.ValueChangedFcn = createCallbackFcn(app, @CommentsTextAreaValueChanged, true);
            app.NameIDEditFieldLabel = uilabel(app.SetUpTab);
            app.NameIDEditFieldLabel.Text = 'Name ID';
            app.NameIDEditField = uieditfield(app.SetUpTab, 'text');
            app.NameIDEditField.ValueChangedFcn = createCallbackFcn(app, @NameIDEditFieldValueChanged, true);
            app.SetUpLabel = uilabel(app.SetUpTab);
            app.SetUpLabel.Text = 'Set-Up';
            app.TaskOptionsTab = uitab(app.TabGroup);
            app.TaskOptionsTab.Title = 'Task Options';
            app.TaskOptionsLabel = uilabel(app.TaskOptionsTab);
            app.TaskOptionsLabel.Text = 'Task Options';
            app.StimulationDurationEditFieldLabel = uilabel(app.TaskOptionsTab);
            app.StimulationDurationEditFieldLabel.Text = 'Stimulation Duration';
            app.StimulationDurationEditField = uieditfield(app.TaskOptionsTab, 'numeric');
            app.StimulationDurationEditField.ValueChangedFcn = createCallbackFcn(app, @StimulationDurationEditFieldValueChanged, true);
            app.StimulationDurationEditField.Value = 300;
            app.NumberofColorsSpinnerLabel = uilabel(app.TaskOptionsTab);
            app.NumberofColorsSpinnerLabel.Text = 'Number of Colors';
            app.NumberofColorsSpinner = uispinner(app.TaskOptionsTab);
            app.NumberofColorsSpinner.Limits = [3 10];
            app.NumberofColorsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumberofColorsSpinnerValueChanged, true);
            app.NumberofColorsSpinner.Value = 10;
            app.ShowTimeSwitchLabel = uilabel(app.TaskOptionsTab);
            app.ShowTimeSwitchLabel.Text = 'Show Time';
            app.ShowTimeSwitch = uiswitch(app.TaskOptionsTab, 'slider');
            app.ShowTimeSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowTimeSwitchValueChanged, true);
            app.ColorsPanel = uipanel(app.TaskOptionsTab);
            app.ColorsPanel.Title = 'Colors';
            app.PurpleCheckBox = uicheckbox(app.ColorsPanel);
            app.PurpleCheckBox.Text = 'Purple';
            app.PurpleCheckBox.Value = true;
            app.GreenCheckBox = uicheckbox(app.ColorsPanel);
            app.GreenCheckBox.Text = 'Green';
            app.GreenCheckBox.Value = true;
            app.RedCheckBox = uicheckbox(app.ColorsPanel);
            app.RedCheckBox.Text = 'Red';
            app.OrangeCheckBox = uicheckbox(app.ColorsPanel);
            app.OrangeCheckBox.Text = 'Orange';
            app.BlueCheckBox = uicheckbox(app.ColorsPanel);
            app.BlueCheckBox.Text = 'Blue';
            app.BlueCheckBox.Value = true;
            app.GrayCheckBox = uicheckbox(app.ColorsPanel);
            app.GrayCheckBox.Text = 'Gray';
            app.GrayCheckBox.Value = true;
            app.WhiteCheckBox = uicheckbox(app.ColorsPanel);
            app.WhiteCheckBox.Text = 'White';
            app.WhiteCheckBox.Value = true;
            app.YellowCheckBox = uicheckbox(app.ColorsPanel);
            app.YellowCheckBox.Text = 'Yellow';
            app.YellowCheckBox.Value = true;
            app.PinkCheckBox = uicheckbox(app.ColorsPanel);
            app.PinkCheckBox.Text = 'Pink';
            app.BrownCheckBox = uicheckbox(app.ColorsPanel);
            app.BrownCheckBox.Text = 'Brown';
            app.BrownCheckBox.Value = true;
            app.TaskTab = uitab(app.TabGroup);
            app.TaskTab.Title = 'Task';
            app.STARTButton = uibutton(app.TaskTab, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.NoSaveButton = uibutton(app.UIFigure, 'push');
            app.NoSaveButton.ButtonPushedFcn = createCallbackFcn(app, @NoSaveButtonPushed, true);
            app.NoSaveButton.Text = 'No Save';
            app.PHOTODIODELabel = uilabel(app.UIFigure);
            app.PHOTODIODELabel.Text = 'PHOTODIODE';
            app.CUMULATIVETIMELabel = uilabel(app.UIFigure);
            app.CUMULATIVETIMELabel.Text = 'CUMULATIVE TIME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Stroop_Task

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end